classdef twolink_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1  matlab.ui.Figure
        text3    matlab.ui.control.Label
        text2    matlab.ui.control.Label
        text1    matlab.ui.control.Label
        Create   matlab.ui.control.Button
        slider2  matlab.ui.control.Slider
        slider1  matlab.ui.control.Slider
        axes1    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot =[]; % This is a Serial Link Created robot 
        jointangles= [ 0 0]; % these are the joint angles of the robot
        
    end
    
    properties (Access = public)
        Property3 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Create
        function Create_Callback(app, event)
            %create a simple two link robot.
            L(1) = Link([0 0 1 0]);
            L(2) = Link([0 0 1 0]);
            app.robot = SerialLink(L, 'name', 'robot');
            app.robot.plot (app.jointangles);
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            %get the values of the sliders from each joint
            app.jointangles(1) = app.slider1.Value;
            app.jointangles(2) = app.slider2.Value;
            %plot the robot
            app.robot.plot ( app.jointangles);
            T = app.robot.fkine([0 0]);
            %q = app.robot.ikine(T,[0 0], 'm', 1);
            %q
        end

        % Value changed function: slider2
        function slider2_Callback(app, event)
            app.jointangles(1) = app.slider1.Value;
            app.jointangles(2) = app.slider2.Value;
            app.robot.plot (app.jointangles);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'twolink';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [0 6.28];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider2 = uislider(app.figure1);
            app.slider2.Limits = [0 6.28];
            app.slider2.MajorTicks = [];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2_Callback, true);
            app.slider2.MinorTicks = [];
            app.slider2.Tag = 'slider2';
            app.Create = uibutton(app.figure1, 'push');
            app.Create.ButtonPushedFcn = createCallbackFcn(app, @Create_Callback, true);
            app.Create.Tag = 'Create';
            app.Create.Text = 'Create Robot';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = '2 link or not 2 link....';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Joint 1';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Joint 2';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = twolink_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end